classdef WidefieldAquisitionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WidefieldImagingDataAquisitionLabel  matlab.ui.control.Label
        MouseEditFieldLabel            matlab.ui.control.Label
        MouseEditField                 matlab.ui.control.EditField
        ExperimenterEditFieldLabel     matlab.ui.control.Label
        ExperimenterEditField          matlab.ui.control.EditField
        SaveDirectoryEditFieldLabel    matlab.ui.control.Label
        SaveDirectoryEditField         matlab.ui.control.EditField
        Button                         matlab.ui.control.Button
        ExperimentEditFieldLabel       matlab.ui.control.Label
        ExperimentEditField            matlab.ui.control.EditField
        RecordingRoutineDropDownLabel  matlab.ui.control.Label
        RecordingRoutineDropDown       matlab.ui.control.DropDown
        EditRoutineButton              matlab.ui.control.Button
        IlluminationWavelengthControlPanel  matlab.ui.container.Panel
        BehavioralMonitoringPanel      matlab.ui.container.Panel
        TestCamerasButton              matlab.ui.control.Button
        ConfigureButton                matlab.ui.control.Button
        RESETButton                    matlab.ui.control.Button
        RECORDButton                   matlab.ui.control.Button
        RecordingLampLabel             matlab.ui.control.Label
        RecordingLamp                  matlab.ui.control.Lamp
    end

    
    properties (Access = private) %These are the local variables to this frame of the app (Access = public; are the Global variables) 
        rootdir %The root directory of the GUI, flexibly identified for locating all files and setting path
        cur_routine_vars =[];
        cur_routine_vals =[];
        
    end
   
    
    methods (Access = private)
        
        function MissingVariable_catch(app,missing_var)
            for i = 1:numel(missing_var)
                if ~exist(missing_var{i})
                    uialert(app.UIFigure,sprintf('%s Variable has not been defined yet',missing_var{i}),'Undefined Variable')            
                end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %Add all the GUI subfolders to your path
           app.rootdir = fileparts(which('WidefieldAquisitionApp.mlapp'));
           if isempty(app.rootdir); error("Failed to find WidefieldAquisitionApp.mlapp in matlab path."); end           
           addpath(genpath(app.rootdir));
           
           %Default savedir
           app.SaveDirectoryEditField.Value = [app.rootdir filesep 'saved_data' filesep];
           
           %Load the available routines
           routines = getRoutines([app.rootdir filesep 'Routines']);                     
           app.RecordingRoutineDropDown.Items = routines; 
           app.RecordingRoutineDropDown.Value = 'choose_routine';
           
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %User selects save directory              
            app.SaveDirectoryEditField.Value = uigetdir('C:\');
        end

        % Button pushed function: EditRoutineButton
        function EditRoutineButtonPushed(app, event)
            %Catch to make sure it's not on the default place holder routine
            if strcmp(app.RecordingRoutineDropDown.Value,'choose_routine')
                uialert(app.UIFigure,'You First Must Choose a Routine','Missing Routine')        
                return
            end
            
            temp_routine = DynamicDialog(app.cur_routine_vars,app.cur_routine_vals);
            if ~isempty(temp_routine) %repopulate the routine options.  
                app.cur_routine_vals = temp_routine;
            end
            
            %Update the mutable fields in the GUI
            app.ExperimentEditField.Value = app.cur_routine_vals.ExperimentType;
            app.ExperimenterEditField.Value = app.cur_routine_vals.experimenter;
            app.MouseEditField.Value = app.cur_routine_vals.mouse;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           % Delete app 
           delete(app)                
        end

        % Value changed function: RecordingRoutineDropDown
        function RecordingRoutineDropDownValueChanged(app, event)
           
            %Load data from the selected routine
            [app.cur_routine_vars, app.cur_routine_vals] = feval(app.RecordingRoutineDropDown.Value,...                
                app.MouseEditField.Value,...
                app.ExperimenterEditField.Value,...
                app.ExperimentEditField.Value);             
        end

        % Value changed function: MouseEditField
        function MouseEditFieldValueChanged(app, event)
            if ~isempty(app.cur_routine_vals) %if you have already populated the routine options, then change these values. Otherwise skip
                app.cur_routine_vals.mouse = app.MouseEditField.Value;
            end            
        end

        % Value changed function: ExperimenterEditField
        function ExperimenterEditFieldValueChanged(app, event)
            if ~isempty(app.cur_routine_vals) %if you have already populated the routine options, then change these values. Otherwise skip
                app.cur_routine_vals.experimenter = app.ExperimenterEditField.Value;
            end
            
        end

        % Value changed function: ExperimentEditField
        function ExperimentEditFieldValueChanged(app, event)
            if ~isempty(app.cur_routine_vals) %if you have already populated the routine options, then change these values. Otherwise skip
                app.cur_routine_vals.ExperimentType = app.ExperimentEditField.Value;
            end
            
        end

        % Button pushed function: TestCamerasButton
        function TestCamerasButtonPushed(app, event)
            filename = CreateVideoRecordingScript([app.rootdir filesep 'Behavioral_MultiCam' filesep],'Record',0);
            cmd = sprintf('python "%s" && exit &',filename);
            system(cmd) 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WidefieldImagingDataAquisitionLabel = uilabel(app.UIFigure);
            app.WidefieldImagingDataAquisitionLabel.Text = 'Widefield Imaging Data Aquisition';
            app.MouseEditFieldLabel = uilabel(app.UIFigure);
            app.MouseEditFieldLabel.Text = 'Mouse';
            app.MouseEditField = uieditfield(app.UIFigure, 'text');
            app.MouseEditField.ValueChangedFcn = createCallbackFcn(app, @MouseEditFieldValueChanged, true);
            app.MouseEditField.Value = '1';
            app.ExperimenterEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimenterEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimenterEditFieldValueChanged, true);
            app.ExperimenterEditField.Value = 'elmo';
            app.SaveDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.SaveDirectoryEditFieldLabel.Text = 'Save Directory';
            app.SaveDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.SaveDirectoryEditField.Value = 'C:\Users\mouse1\Desktop';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.ExperimentEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentEditFieldValueChanged, true);
            app.ExperimentEditField.Value = 'restingstate';
            app.RecordingRoutineDropDownLabel = uilabel(app.UIFigure);
            app.RecordingRoutineDropDownLabel.Text = 'Recording Routine';
            app.RecordingRoutineDropDown = uidropdown(app.UIFigure);
            app.RecordingRoutineDropDown.Items = {'select_routine', 'Routine1', 'Routine2', 'Routine3'};
            app.RecordingRoutineDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingRoutineDropDownValueChanged, true);
            app.RecordingRoutineDropDown.Value = 'select_routine';
            app.EditRoutineButton = uibutton(app.UIFigure, 'push');
            app.EditRoutineButton.ButtonPushedFcn = createCallbackFcn(app, @EditRoutineButtonPushed, true);
            app.EditRoutineButton.Text = 'Edit Routine';
            app.IlluminationWavelengthControlPanel = uipanel(app.UIFigure);
            app.IlluminationWavelengthControlPanel.Title = 'Illumination Wavelength Control';
            app.BehavioralMonitoringPanel = uipanel(app.UIFigure);
            app.BehavioralMonitoringPanel.Title = 'Behavioral Monitoring';
            app.TestCamerasButton = uibutton(app.BehavioralMonitoringPanel, 'push');
            app.TestCamerasButton.ButtonPushedFcn = createCallbackFcn(app, @TestCamerasButtonPushed, true);
            app.TestCamerasButton.Text = 'Test Camera(s)';
            app.ConfigureButton = uibutton(app.BehavioralMonitoringPanel, 'push');
            app.ConfigureButton.Text = 'Configure';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.Text = 'RESET';
            app.RECORDButton = uibutton(app.UIFigure, 'push');
            app.RECORDButton.Text = 'RECORD';
            app.RecordingLampLabel = uilabel(app.UIFigure);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = WidefieldAquisitionApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end